classdef IHM_FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DviewMenu                      matlab.ui.container.Menu
        InfoMenu                       matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        Lamp                           matlab.ui.control.Lamp
        StartButton                    matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        DronesperdusEditField          matlab.ui.control.NumericEditField
        DronesperdusEditFieldLabel     matlab.ui.control.Label
        EventLoggingLabel              matlab.ui.control.Label
        TempscoulLabel                 matlab.ui.control.Label
        DronesnondploysEditField       matlab.ui.control.NumericEditField
        DronesnondploysEditFieldLabel  matlab.ui.control.Label
        DronesdploysEditField          matlab.ui.control.NumericEditField
        DronesdploysEditFieldLabel     matlab.ui.control.Label
        dtSlider                       matlab.ui.control.Slider
        dtSliderLabel                  matlab.ui.control.Label
        tempscouleEditField            matlab.ui.control.EditField
        Console                        matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        ObservationTab                 matlab.ui.container.Tab
        PanDNButton                    matlab.ui.control.Button
        PanUPButton                    matlab.ui.control.Button
        RotationGAButton               matlab.ui.control.Button
        RotationDRButton               matlab.ui.control.Button
        TranslationGAButton            matlab.ui.control.Button
        TranslationARButton            matlab.ui.control.Button
        TranslationDRButton            matlab.ui.control.Button
        TranslationAVButton            matlab.ui.control.Button
        FlotteTab                      matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        ZonesTab                       matlab.ui.container.Tab
        UITable_2                      matlab.ui.control.Table
        CiblesTab                      matlab.ui.container.Tab
        UITable_3                      matlab.ui.control.Table
        ComportementTab                matlab.ui.container.Tab
        UIAxes3D_2                     matlab.ui.control.UIAxes
        UIAxes3D                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        plotHandles  % struct to store scatter, quiver, trace objects
        simTimer     % MATLAB timer for repeated updates

        env                  % Environment object
        swarm                % SwarmManager object
        %simTimer             % Timer for the simulation loop
        simulationRunning    logical = false
        simulationPaused     logical = false
    
        % For real-time plotting
        dronePlotHandles     = []   % Store handles to drone plots (2D/3D)
        traceSize            = 20
        simulationTime       double = 0
        maxSimulationTime    double = 100000
    end
    
    methods (Access = private)
    
        function setupSimulation(app)
            clear all
            close all
            clc

            app.maxSimulationTime = 100000;
            
            % Clear console and logs
            app.Console.Value = {''};
            app.tempscouleEditField.Value = "0";
            app.simulationTime = 0;
    
            % --- 1) Create Environment ---
            % Example: env = Environment(10, 200, [-200, 200, 200, -200], ...)
            % TODO: Adapt to your actual constructor
            app.env = Environment(10, 200, ...
                [-200, 200, 200, -200], ...
                [-200, -200, 200, 200], ...
                [0,0,0,0]);
    
            % If you have a separate script to set up zones:
            %%setupZones;  %ok
            % Alternatively: app.setupZones() if you integrate the logic in an App method.
    
            % --- 2) Create Swarm & Initialize Drones ---
            % Example:
            app.swarm = SwarmManager(app.env, app.maxSimulationTime);
    
            % TODO: adapt these to your actual numbers
            numMultirotor = 10;  
            numFixedwing = 5;  
            homeBaseCoord = [0, 0, 0];
    
            for i = 1:numMultirotor
                app.swarm.addDrone('multirotor', homeBaseCoord);
                % If you have specific flags:
                app.swarm.MultiRotor{i}.mode_Follow_waypoint = false;
            end
    
            for i = 1:numFixedwing
                app.swarm.addDrone('fixedwing', homeBaseCoord);
                app.swarm.FixedWing{i}.mode_Follow_waypoint = false;
            end
    
            % Add waypoints, targets, weights, etc.
            Waypoints = [0 50 100;
                         0 0 50;
                         100 100 50;
                         100 -100 100;
                         -100 -100 50;
                         -100 100 100;
                         -100 -10 10];
            for i = 1:length(app.swarm.Drones)
                app.swarm.Drones{i}.Waypoints = Waypoints;
            end
    
            % Example target logic
            app.swarm.targets = [1 0 0; 140 140 30; 6 8 7];
            Target = [0 0 75];
            app.swarm.update_target(Target,1);
            app.swarm.Drones{end}.setTargetGroup(2);
            app.swarm.Drones{1}.mode_Follow_waypoint = true;
    
            % Example swarm weights
            app.swarm.r = [30 60 100];
            app.swarm.swarm_weights = [1.4 0.8];
            app.swarm.weights = [0.5 1.2 1 10] / 10;
    
            % Set initial UI fields
            app.DronesdploysEditField.Value = numMultirotor + numFixedwing;
            app.DronesnondploysEditField.Value = 0;
            app.DronesperdusEditField.Value = 0;
    
            % Clear existing plots
            cla(app.UIAxes3D, 'reset');
            cla(app.UIAxes3D_2, 'reset');
    
            title(app.UIAxes3D, 'Affichage 2D');
            xlabel(app.UIAxes3D, 'X'); ylabel(app.UIAxes3D, 'Y');
            zticks(app.UIAxes3D, []);  % 2D view
    
            title(app.UIAxes3D_2, 'Affichage 3D');
            xlabel(app.UIAxes3D_2, 'X'); ylabel(app.UIAxes3D_2, 'Y'); zlabel(app.UIAxes3D_2, 'Z');
    
            % Optionally initialize tables if you want them empty at first
            app.UITable.Data = {};
            app.UITable_2.Data = {};
            app.UITable_3.Data = {};
    
            % If you want to create shape objects or advanced terrain/zone visuals, do it here.
    
            % Just log that we have set up
            addConsoleMsg(app, "Simulation environment and swarm initialized.");

            app.plotHandles = RTPlot2_init(app.UIAxes3D_2, app.env, app.swarm, app.traceSize);

            % Configure and start your timer:
            app.simTimer = timer('ExecutionMode','fixedRate','Period',0.05, ...
                                 'TimerFcn',@(~,~) updateSimulation(app));
            start(app.simTimer);
        end

        function startSimulationTimer(app)
            % Create a new timer only if it doesn't exist
            if isempty(app.simTimer) || ~isvalid(app.simTimer)
                app.simTimer = timer( ...
                    'ExecutionMode','fixedRate',...
                    'Period', app.dtSlider.Value,...  % Use the dt from your slider
                    'BusyMode','drop',...
                    'TimerFcn', @(~,~)updateSimulation(app));
            else
                % If timer already exists, reconfigure it if needed
                app.simTimer.Period = app.dtSlider.Value;
                app.simTimer.TimerFcn = @(~,~)updateSimulation(app);
            end
    
            start(app.simTimer);
            app.simulationRunning = true;
            app.simulationPaused  = false;
            app.Lamp.Color = [0 1 0];  % Green lamp
            addConsoleMsg(app, "Simulation started.");
        end

        function updateSimulation(app)
            if ~app.simulationPaused
                % 1) Update environment & swarm
                dt = app.dtSlider.Value;
                app.simulationTime = app.simulationTime + dt;
    
                % This method is called repeatedly by the timer
                % 1) Advance your swarm by dt
                app.swarm.update_speeds(dt);  % or whatever your dt is
        
                % 2) Update the visuals
                RTPlot2_update(app.plotHandles, app.swarm);
                drawnow limitrate;
    
                % 3) Update UI components (labels, tables, etc.)
                app.tempscouleEditField.Value = sprintf("%.1f", app.simulationTime);
                
                % Here you might do:
                % app.DronesdploysEditField.Value     = numberOfDeployed;
                % app.DronesnondploysEditField.Value  = numberOfNotDeployed;
                % app.DronesperdusEditField.Value     = numberOfLostDrones;
                %
                % If you want to fill the Fleet/Zone/Target tables:
                % app.UITable.Data    = getDroneTableData(app.swarm);
                % app.UITable_2.Data  = getZoneTableData(app.env);
                % app.UITable_3.Data  = getTargetTableData(app.swarm);
    
                % 4) Check if we exceed maximum simulation time
                if app.simulationTime >= app.maxSimulationTime
                    stopSimulationTimer(app);
                end
            end
        end
    
        function stopSimulationTimer(app)
            if ~isempty(app.simTimer) && isvalid(app.simTimer)
                stop(app.simTimer);
            end
            app.simulationRunning = false;
            app.Lamp.Color = [1 0 0];  % Red
            addConsoleMsg(app, "Simulation stopped.");
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~app.simulationRunning
                % Initialize or re-initialize the simulation
                setupSimulation(app);
                startSimulationTimer(app);
            elseif app.simulationPaused
                % If it's paused, just unpause it
                app.simulationPaused = false;
                app.Lamp.Color = [0 1 0];
                addConsoleMsg(app, "Simulation resumed.");
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if app.simulationRunning
                stopSimulationTimer(app);
            end
            % Re-initialize everything
            setupSimulation(app);
            app.Lamp.Color = [1 0 0];
            addConsoleMsg(app, "Simulation reset.");
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.simulationRunning && ~app.simulationPaused
                app.simulationPaused = true;
                app.Lamp.Color = [1 1 0]; % Yellow
                addConsoleMsg(app, "Simulation paused.");
            elseif app.simulationRunning && app.simulationPaused
                % If paused, pressing Pause again could resume or do nothing
                app.simulationPaused = false;
                app.Lamp.Color = [0 1 0];
                addConsoleMsg(app, "Simulation resumed.");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DviewMenu = uimenu(app.UIFigure);
            app.DviewMenu.Text = '3Dview';
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.Text = 'Info';
            app.Menu = uimenu(app.InfoMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.InfoMenu);
            app.Menu2.Text = 'Menu2';
            app.UIAxes3D = uiaxes(app.UIFigure);
            title(app.UIAxes3D, 'Affichage 2D')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            app.UIAxes3D.ZTick = [];
            app.UIAxes3D_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3D_2, 'Affichage 3D')
            xlabel(app.UIAxes3D_2, 'X')
            ylabel(app.UIAxes3D_2, 'Y')
            zlabel(app.UIAxes3D_2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ObservationTab = uitab(app.TabGroup);
            app.ObservationTab.Title = 'Observation';
            app.TranslationAVButton = uibutton(app.ObservationTab, 'push');
            app.TranslationAVButton.Text = 'Translation AV';
            app.TranslationDRButton = uibutton(app.ObservationTab, 'push');
            app.TranslationDRButton.Text = 'Translation DR';
            app.TranslationARButton = uibutton(app.ObservationTab, 'push');
            app.TranslationARButton.Text = 'Translation AR';
            app.TranslationGAButton = uibutton(app.ObservationTab, 'push');
            app.TranslationGAButton.Text = 'Translation GA';
            app.RotationDRButton = uibutton(app.ObservationTab, 'push');
            app.RotationDRButton.Text = 'Rotation DR';
            app.RotationGAButton = uibutton(app.ObservationTab, 'push');
            app.RotationGAButton.Text = 'Rotation GA';
            app.PanUPButton = uibutton(app.ObservationTab, 'push');
            app.PanUPButton.Text = 'Pan UP';
            app.PanDNButton = uibutton(app.ObservationTab, 'push');
            app.PanDNButton.Text = 'Pan DN';
            app.FlotteTab = uitab(app.TabGroup);
            app.FlotteTab.Title = 'Flotte';
            app.UITable = uitable(app.FlotteTab);
            app.UITable.ColumnName = {'Liste'; 'Coordonnées'; 'Type'; 'Statut'; 'Phase de Mission'; 'Cible Allouée'};
            app.UITable.RowName = {};
            app.ZonesTab = uitab(app.TabGroup);
            app.ZonesTab.Title = 'Zones';
            app.UITable_2 = uitable(app.ZonesTab);
            app.UITable_2.ColumnName = {'Liste'; 'Coordonnées'; 'Type'; 'Statut'; 'Temps de Violation'};
            app.UITable_2.RowName = {};
            app.CiblesTab = uitab(app.TabGroup);
            app.CiblesTab.Title = 'Cibles';
            app.UITable_3 = uitable(app.CiblesTab);
            app.UITable_3.ColumnName = {'Liste'; 'Coordonnées'; 'Flotte Allouée'; 'Statut'; 'Score d''Observabilité'};
            app.UITable_3.RowName = {};
            app.ComportementTab = uitab(app.TabGroup);
            app.ComportementTab.Title = 'Comportement';
            app.Panel = uipanel(app.UIFigure);
            app.Console = uitextarea(app.Panel);
            app.tempscouleEditField = uieditfield(app.Panel, 'text');
            app.dtSliderLabel = uilabel(app.Panel);
            app.dtSliderLabel.Text = 'dt';
            app.dtSlider = uislider(app.Panel);
            app.dtSlider.Limits = [0.03 10];
            app.dtSlider.Value = 0.03;
            app.DronesdploysEditFieldLabel = uilabel(app.Panel);
            app.DronesdploysEditFieldLabel.Text = 'Drones déployés';
            app.DronesdploysEditField = uieditfield(app.Panel, 'numeric');
            app.DronesnondploysEditFieldLabel = uilabel(app.Panel);
            app.DronesnondploysEditFieldLabel.Text = 'Drones non déployés';
            app.DronesnondploysEditField = uieditfield(app.Panel, 'numeric');
            app.TempscoulLabel = uilabel(app.Panel);
            app.TempscoulLabel.Text = 'Temps écoulé';
            app.EventLoggingLabel = uilabel(app.Panel);
            app.EventLoggingLabel.Text = 'Event Logging';
            app.DronesperdusEditFieldLabel = uilabel(app.Panel);
            app.DronesperdusEditFieldLabel.Text = 'Drones perdus';
            app.DronesperdusEditField = uieditfield(app.Panel, 'numeric');
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PauseButton = uibutton(app.Panel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Lamp = uilamp(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IHM_FINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end